classdef lemon_battery < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        mainscreenTab          matlab.ui.container.Tab
        VoltageGauge           matlab.ui.control.Gauge
        VoltageGaugeLabel      matlab.ui.control.Label
        InformationPanel       matlab.ui.container.Panel
        GoToQuizButton         matlab.ui.control.Button
        RandomTipFunFactLabel  matlab.ui.control.Label
        nexttipButton          matlab.ui.control.Button
        prevtipButton          matlab.ui.control.Button
        tip_text               matlab.ui.control.TextArea
        ControlsPanel          matlab.ui.container.Panel
        FruitDropDown          matlab.ui.control.DropDown
        FruitDropDownLabel     matlab.ui.control.Label
        RipenessSlider         matlab.ui.control.Slider
        RipenessSliderLabel    matlab.ui.control.Label
        OutputLamp             matlab.ui.control.Lamp
        OutputLampLabel        matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        quizscreenTab          matlab.ui.container.Tab
        QuizLabel              matlab.ui.control.Label
        question_text          matlab.ui.control.TextArea
        PrevQuestionButton     matlab.ui.control.Button
        NextQuestionButton     matlab.ui.control.Button
        question_index_label   matlab.ui.control.Label
        AnswerButtonGroup      matlab.ui.container.ButtonGroup
        SubmitButton           matlab.ui.control.Button
        resultsscreenTab       matlab.ui.container.Tab
        ResultsLabel           matlab.ui.control.Label
        LearnSomeMoreButton    matlab.ui.control.Button
        TryAgainButton         matlab.ui.control.Button
        score_disp             matlab.ui.control.TextArea
    end


    properties (Access = private)
        num_questions int32
        question_index int32  % keeps track of the current question
        questions string % list of the questions
        answers string % list of the options
        answer_buttons matlab.ui.control.RadioButton
        correct int32 % list of the correct answers
        scores int32 % how much each question is worth
        selections int32
        fact_timer timer % timer to control the swapping of facts
        fact_index int32
        facts string
        background_color string
        foreground_color string
        bright_background_color string
        bright_foreground_color string
        dark_background_color string

    end

    methods (Access = private)

        function timer_callback(app)
            n_facts = length(app.facts);

            if app.fact_index == n_facts
                app.fact_index = 1;
            else
                app.fact_index = app.fact_index + 1;
            end

            app.tip_text.Value = app.facts(app.fact_index);

        end

        function set_question(app, index)
            [~,num_answers] = size(app.answers);
            app.question_text.Value = app.questions(index);
            app.question_index = index;

            if index > 1 
                app.PrevQuestionButton.Visible = "on";
                app.PrevQuestionButton.Enable="on";
            else
                app.PrevQuestionButton.Visible = "off";
                app.PrevQuestionButton.Enable = "off";
            end

            if index < num_answers 
                app.NextQuestionButton.Visible = "on";
                app.NextQuestionButton.Enable = "on";

            else
                app.NextQuestionButton.Visible = "off";
                app.NextQuestionButton.Enable = "off";
            end

            % app.answerDropDown.ItemsData = app.answers(index,:);
            % app.answerDropDown.Items = {"test 1", "test 2", "test3"};
       
            app.answer_buttons;

            % [~,~, width, height] = app.AnswerButtonGroup.Position;

            height = 28;

            for i = 1:length(app.answer_buttons)
                delete(app.answer_buttons(i));
            end
 
            clear app.answer_buttons;

            for i = 1:num_answers

                button = uiradiobutton(app.AnswerButtonGroup);
                button.Text = app.answers(index, i);
                button.FontColor = app.foreground_color;
                % button.BackgroundColor = app.background_color;
                disp(app.answers(index,i));
                button.Position(2) = (height) * (i-1);
                disp(button.Position);

                app.answer_buttons(i) = button;

            end

            disp(app.answer_buttons);

            app.question_index_label.Text = sprintf("Question %d/%d", index, app.num_questions);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.bright_foreground_color = "#ffffff";
            app.foreground_color = "#ffffff";
            app.background_color = "#11111b";
            app.dark_background_color = "#08080d";
            app.bright_background_color = "#181825";
            border_color = "#ffffff";
            border_width = 1;

            % this assigns all the colors for the different components, and
            % I feel like there should be a better way to do this, but I
            % don't know.
            % but essentially the scheme has five colors,
            % normal foreground and background colors
            % bright foreground and background colors
            % and a dark background color
            % most components use the regular foreground and background
            % colors for their background and foreground/font colors,
            % however, title labels use the bright foreground color, text
            % boxes use both bright colors, and buttons use the dark
            % background and bright foreground.


            % main tab
            app.mainscreenTab.BackgroundColor = app.background_color;
            app.mainscreenTab.ForegroundColor = app.foreground_color;

            app.ControlsPanel.BackgroundColor = app.background_color;
            app.ControlsPanel.ForegroundColor = app.foreground_color;
            app.ControlsPanel.BorderColor = border_color;
            app.ControlsPanel.BorderWidth = border_width;

            app.RipenessSliderLabel.FontColor = app.foreground_color;
            app.RipenessSlider.FontColor = app.foreground_color;
            
            app.InformationPanel.BackgroundColor = app.background_color;
            app.InformationPanel.ForegroundColor = app.foreground_color;
            app.InformationPanel.BorderColor = border_color;
            app.InformationPanel.BorderWidth = border_width;

            app.RandomTipFunFactLabel.FontColor = app.foreground_color;
           

            app.FruitDropDown.BackgroundColor = app.background_color;
            app.FruitDropDown.FontColor = app.foreground_color;
           

            app.FruitDropDownLabel.BackgroundColor = app.background_color;
            app.FruitDropDownLabel.FontColor=app.foreground_color;

            app.tip_text.BackgroundColor = app.bright_background_color;
            app.tip_text.FontColor = app.bright_foreground_color;
            
            app.OutputLampLabel.BackgroundColor = app.background_color;
            app.OutputLampLabel.FontColor = app.foreground_color;

            app.VoltageGaugeLabel.BackgroundColor = app.background_color;
            app.VoltageGaugeLabel.FontColor = app.foreground_color;

            app.VoltageGauge.BackgroundColor = app.background_color;
            app.VoltageGauge.FontColor = app.foreground_color;

            app.prevtipButton.BackgroundColor = app.dark_background_color;
            app.prevtipButton.FontColor = app.bright_foreground_color;

            app.nexttipButton.BackgroundColor = app.dark_background_color;
            app.nexttipButton.FontColor = app.bright_foreground_color;

            app.GoToQuizButton.BackgroundColor = app.dark_background_color;
            app.GoToQuizButton.FontColor = app.bright_foreground_color;


            % quiz tab
            app.quizscreenTab.BackgroundColor = app.background_color;
            app.QuizLabel.BackgroundColor = app.background_color;
            app.QuizLabel.FontColor = app.bright_foreground_color;
            app.question_index_label.BackgroundColor = app.background_color;
            app.question_index_label.FontColor = app.foreground_color;
            app.question_text.BackgroundColor = app.bright_background_color;
            app.question_text.FontColor = app.bright_foreground_color;

            app.AnswerButtonGroup.BackgroundColor = app.bright_background_color;
            app.AnswerButtonGroup.ForegroundColor = app.foreground_color;


            app.PrevQuestionButton.BackgroundColor = app.dark_background_color;
        
            app.PrevQuestionButton.FontColor = app.bright_foreground_color;

            app.NextQuestionButton.BackgroundColor = app.dark_background_color;
            app.NextQuestionButton.FontColor = app.bright_foreground_color;

            app.SubmitButton.BackgroundColor = app.dark_background_color;
            app.SubmitButton.FontColor = app.bright_foreground_color;


            % results tab
            app.resultsscreenTab.BackgroundColor = app.background_color;
            app.ResultsLabel.BackgroundColor = app.background_color;
            app.ResultsLabel.FontColor = app.bright_foreground_color;
            app.score_disp.BackgroundColor = app.bright_background_color;
            app.score_disp.FontColor = app.bright_foreground_color;

            app.TryAgainButton.BackgroundColor = app.dark_background_color;
            app.TryAgainButton.FontColor = app.bright_foreground_color;
               
            app.LearnSomeMoreButton.BackgroundColor = app.dark_background_color;
            app.LearnSomeMoreButton.FontColor = app.bright_foreground_color;


            app.fact_index = 1;
            app.facts = load('facts.mat').facts;
            app.fact_timer = timer;
            app.fact_timer.TimerFcn = @(varargin)app.timer_callback;
            app.fact_timer.ExecutionMode = "fixedRate";
            app.fact_timer.Period=10;
            start(app.fact_timer);

            % quiz data loading

            app.questions = load("quiz.mat").questions;
            app.answers = load("quiz.mat").answers;
            app.correct = load("quiz.mat").correct;
            app.scores = load("quiz.mat").scores;

            app.question_index=1;
            app.num_questions = length(app.questions);

            app.set_question(1);


        end

        % Button pushed function: NextQuestionButton
        function NextQuestionButtonPushed(app, event)
            if app.question_index < app.num_questions
                % app.PrevQuestionButton.Visible ="on";
                % app.PrevQuestionButton.Enable = "on";


                % app.question_index = app.question_index + 1;

                app.set_question(app.question_index + 1);
            end

            if app.question_index == app.num_questions
                % app.NextQuestionButton.Visible ="off";
                % app.NextQuestionButton.Enable = "off";
            end

        end

        % Button pushed function: prevtipButton
        function prevtipButtonPushed(app, event)
            n_facts = length(app.facts);

            if app.fact_index == 1
                app.fact_index =n_facts;
            else
                app.fact_index = app.fact_index - 1;
            end

            app.tip_text.Value = app.facts(app.fact_index);


        end

        % Button pushed function: nexttipButton
        function nexttipButtonPushed(app, event)
            app.timer_callback();
        end

        % Value changed function: FruitDropDown
        function FruitDropDownValueChanged(app, event)
            value = app.FruitDropDown.Value;
            value = replace(value, " ", "");
            value = lower(value);

            path = append(value, ".png");

            app.Image.ImageSource = path;


        end

        % Button pushed function: GoToQuizButton
        function GoToQuizButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.quizscreenTab;
            app.set_question(1);
        end

        % Button pushed function: PrevQuestionButton
        function PrevQuestionButtonPushed(app, event)
            if app.question_index > 1
                % app.NextQuestionButton.Visible ="on";
                % app.NextQuestionButton.Enable = "on";
                % 
                % 
                % app.question_index = app.question_index - 1;

                app.set_question(app.question_index - 1);
            end

            if app.question_index == 1
                % app.PrevQuestionButton.Visible ="off";
                % app.PrevQuestionButton.Enable = "off";
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            % TODO: calculate score for quiz
            app.TabGroup.SelectedTab = app.resultsscreenTab;

        end

        % Button pushed function: TryAgainButton
        function TryAgainButtonPushed(app, event)
            % go back to the quiz
            app.TabGroup.SelectedTab = app.quizscreenTab;
            app.set_question(1);
        end

        % Button pushed function: LearnSomeMoreButton
        function LearnSomeMoreButtonPushed(app, event)
            % go back to the main screen
            app.TabGroup.SelectedTab = app.mainscreenTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.mainscreenTab = uitab(app.TabGroup);
            app.mainscreenTab.Title = 'main screen';
            app.Image = uiimage(app.mainscreenTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'lemon.png');
            app.OutputLampLabel = uilabel(app.mainscreenTab);
            app.OutputLampLabel.Text = 'Output';
            app.OutputLamp = uilamp(app.mainscreenTab);
            app.ControlsPanel = uipanel(app.mainscreenTab);
            app.ControlsPanel.Title = 'Controls';
            app.RipenessSliderLabel = uilabel(app.ControlsPanel);
            app.RipenessSliderLabel.Text = 'Ripeness';
            app.RipenessSlider = uislider(app.ControlsPanel);
            app.FruitDropDownLabel = uilabel(app.ControlsPanel);
            app.FruitDropDownLabel.Text = 'Fruit';
            app.FruitDropDown = uidropdown(app.ControlsPanel);
            app.FruitDropDown.Items = {'Lemon', 'Lime', 'Grapefruit', 'Orange', 'Potato'};
            app.FruitDropDown.ValueChangedFcn = createCallbackFcn(app, @FruitDropDownValueChanged, true);
            app.FruitDropDown.Value = 'Lemon';
            app.InformationPanel = uipanel(app.mainscreenTab);
            app.InformationPanel.Title = 'Information';
            app.tip_text = uitextarea(app.InformationPanel);
            app.tip_text.Editable = 'off';
            app.tip_text.Value = {'you can get different voltages by changing the type of fruit, as well as the ripeness, etc.'};
            app.prevtipButton = uibutton(app.InformationPanel, 'push');
            app.prevtipButton.ButtonPushedFcn = createCallbackFcn(app, @prevtipButtonPushed, true);
            app.prevtipButton.Text = '←     prev tip';
            app.nexttipButton = uibutton(app.InformationPanel, 'push');
            app.nexttipButton.ButtonPushedFcn = createCallbackFcn(app, @nexttipButtonPushed, true);
            app.nexttipButton.Text = 'next tip     →';
            app.RandomTipFunFactLabel = uilabel(app.InformationPanel);
            app.RandomTipFunFactLabel.Text = 'Random Tip / Fun Fact';
            app.GoToQuizButton = uibutton(app.InformationPanel, 'push');
            app.GoToQuizButton.ButtonPushedFcn = createCallbackFcn(app, @GoToQuizButtonPushed, true);
            app.GoToQuizButton.Text = 'Go To Quiz';
            app.VoltageGaugeLabel = uilabel(app.mainscreenTab);
            app.VoltageGaugeLabel.Text = 'Voltage';
            app.VoltageGauge = uigauge(app.mainscreenTab, 'circular');
            app.VoltageGauge.Limits = [0 1];
            app.quizscreenTab = uitab(app.TabGroup);
            app.quizscreenTab.Title = 'quiz screen';
            app.SubmitButton = uibutton(app.quizscreenTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.AnswerButtonGroup = uibuttongroup(app.quizscreenTab);
            app.AnswerButtonGroup.Title = 'Answer';
            app.question_index_label = uilabel(app.quizscreenTab);
            app.question_index_label.Text = 'Question ? of ?';
            app.NextQuestionButton = uibutton(app.quizscreenTab, 'push');
            app.NextQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @NextQuestionButtonPushed, true);
            app.NextQuestionButton.Text = 'Next Question';
            app.PrevQuestionButton = uibutton(app.quizscreenTab, 'push');
            app.PrevQuestionButton.ButtonPushedFcn = createCallbackFcn(app, @PrevQuestionButtonPushed, true);
            app.PrevQuestionButton.Text = 'Prev Question';
            app.question_text = uitextarea(app.quizscreenTab);
            app.question_text.Editable = 'off';
            app.question_text.Value = {'question text'};
            app.QuizLabel = uilabel(app.quizscreenTab);
            app.QuizLabel.Text = 'Quiz';
            app.resultsscreenTab = uitab(app.TabGroup);
            app.resultsscreenTab.Title = 'results screen';
            app.score_disp = uitextarea(app.resultsscreenTab);
            app.score_disp.Value = {'you scored: ?/?!'};
            app.TryAgainButton = uibutton(app.resultsscreenTab, 'push');
            app.TryAgainButton.ButtonPushedFcn = createCallbackFcn(app, @TryAgainButtonPushed, true);
            app.TryAgainButton.Text = 'Try Again';
            app.LearnSomeMoreButton = uibutton(app.resultsscreenTab, 'push');
            app.LearnSomeMoreButton.ButtonPushedFcn = createCallbackFcn(app, @LearnSomeMoreButtonPushed, true);
            app.LearnSomeMoreButton.Text = 'Learn Some More';
            app.ResultsLabel = uilabel(app.resultsscreenTab);
            app.ResultsLabel.Text = 'Results!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lemon_battery

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end